classdef CSVtoTimePts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EventFinderUIFigure            matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        EventFinderTab                 matlab.ui.container.Tab
        SelectFilesButton              matlab.ui.control.Button
        FindtimepointsButton           matlab.ui.control.Button
        readyLampLabel                 matlab.ui.control.Label
        readyLamp                      matlab.ui.control.Lamp
        MarkerFileNameEditFieldLabel   matlab.ui.control.Label
        MarkerFileNameEditField        matlab.ui.control.EditField
        SystemStartTimeEditFieldLabel  matlab.ui.control.Label
        SystemStartTimeEditField       matlab.ui.control.NumericEditField
        NumberofMarkersEditFieldLabel  matlab.ui.control.Label
        NumberofMarkersEditField       matlab.ui.control.NumericEditField
        SelectGroupDropDownLabel       matlab.ui.control.Label
        SelectGroupDropDown            matlab.ui.control.DropDown
        ParticipantCheckBox            matlab.ui.control.CheckBox
        CaregiverCheckBox              matlab.ui.control.CheckBox
        PlotGraphforLabel              matlab.ui.control.Label
        ShowMarkersforLabel            matlab.ui.control.Label
        EDACheckBox                    matlab.ui.control.CheckBox
        HRCheckBox                     matlab.ui.control.CheckBox
        TempCheckBox                   matlab.ui.control.CheckBox
        LoadDataLabel                  matlab.ui.control.Label
        SelectDirectoryButton          matlab.ui.control.Button
        SavingDirectoryLabel           matlab.ui.control.Label
        DebugModeSwitchLabel           matlab.ui.control.Label
        DebugModeSwitch                matlab.ui.control.Switch
        GroupThresholdTab              matlab.ui.container.Tab
        SelectGroupDropDown_2Label     matlab.ui.control.Label
        SelectGroupDropDownThreshold   matlab.ui.control.DropDown
        UpdateThresholdButton          matlab.ui.control.Button
        HeartRatepeakprominenceEditFieldLabel  matlab.ui.control.Label
        HeartRatepeakprominenceEditField  matlab.ui.control.NumericEditField
        ElectrodermalActchangeEditFieldLabel  matlab.ui.control.Label
        ElectrodermalActchangeEditField  matlab.ui.control.NumericEditField
        TemperaturechangeEditFieldLabel  matlab.ui.control.Label
        TemperaturechangeEditField     matlab.ui.control.NumericEditField
        SelectUserDropDownLabel        matlab.ui.control.Label
        SelectUserDropDown             matlab.ui.control.DropDown
        xEditFieldLabel                matlab.ui.control.Label
        SF_HR                          matlab.ui.control.NumericEditField
        xEditField_2Label              matlab.ui.control.Label
        SF_EDA                         matlab.ui.control.NumericEditField
        xEditField_3Label              matlab.ui.control.Label
        SF_TEMP                        matlab.ui.control.NumericEditField
        ScalingFactorLabel             matlab.ui.control.Label
        usLabel                        matlab.ui.control.Label
        bpmLabel                       matlab.ui.control.Label
        CLabel                         matlab.ui.control.Label
        INT_EDA                        matlab.ui.control.NumericEditField
        INT_TEMP                       matlab.ui.control.NumericEditField
        sLabel_2                       matlab.ui.control.Label
        sLabel_1                       matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        % Table version of the CSV table
        % Participant
        BVP_p = table();
        HR_p = table();
        TEMP_p = table();
        EDA_p = table();
        % Care-giver
        BVP_c = table();
        HR_c = table();
        TEMP_c = table();
        EDA_c = table();
        
        % Signal quality index
        % Participant
        SQI_TEMP_p = [];
        SQI_EDA_p = [];
        
        % Care-giver
        SQI_TEMP_c = [];
        SQI_EDA_c = [];
        
        % Data structure containing data in workable format
        Data = struct();
        
        % Misc variables
        number_markers = 1;
        group = '';
        thresholds = struct();
        start_time = 0;
        is_ready = 0;
        is_debug = 0;
        
        % Algorithm related variables
        event_type_p = [];
        event_type_c = [];
       
        % default saving directory:
        saving_directory = erase(mfilename('fullpath'),strcat('\',mfilename));
        original_directory = erase(mfilename('fullpath'),strcat('\',mfilename));
        % tag for the folder name
        time_stamp = 0;
        
    end
    
    % Helper functions
    methods (Access = private)
        
        % Function that will populate the group view with data
        function populate_group_threshold(app,selected_group,selected_user)
            % Loading the Group structure
            Group = load('Group.mat');
            Group = Group.Group;
            
            % Changing the right field in the Group structure
            if(strcmp(selected_group,'Blue'))
                if(strcmp(selected_user,'Participant'))
                    app.HeartRatepeakprominenceEditField.Value = Group.blue.p.hr;
                    app.ElectrodermalActchangeEditField.Value = Group.blue.p.eda;
                    app.TemperaturechangeEditField.Value = Group.blue.p.temp;
                    app.SF_HR.Value = Group.blue.p.hr_sf;
                    app.SF_EDA.Value = Group.blue.p.eda_sf;
                    app.SF_TEMP.Value = Group.blue.p.temp_sf;
                    app.INT_EDA.Value = Group.blue.p.eda_int;
                    app.INT_TEMP.Value = Group.blue.p.temp_int;
                elseif(strcmp(selected_user,'Care-giver'))
                    app.HeartRatepeakprominenceEditField.Value = Group.blue.c.hr;
                    app.ElectrodermalActchangeEditField.Value = Group.blue.c.eda;
                    app.TemperaturechangeEditField.Value = Group.blue.c.temp;
                    app.SF_HR.Value = Group.blue.c.hr_sf;
                    app.SF_EDA.Value = Group.blue.c.eda_sf;
                    app.SF_TEMP.Value = Group.blue.c.temp_sf;   
                    app.INT_EDA.Value = Group.blue.c.eda_int;
                    app.INT_TEMP.Value = Group.blue.c.temp_int;
                end
            elseif(strcmp(selected_group,'Green'))
                if(strcmp(selected_user,'Participant'))
                    app.HeartRatepeakprominenceEditField.Value = Group.green.p.hr;
                    app.ElectrodermalActchangeEditField.Value = Group.green.p.eda;
                    app.TemperaturechangeEditField.Value = Group.green.p.temp;
                    app.SF_HR.Value = Group.green.p.hr_sf;
                    app.SF_EDA.Value = Group.green.p.eda_sf;
                    app.SF_TEMP.Value = Group.green.p.temp_sf;
                    app.INT_EDA.Value = Group.green.p.eda_int;
                    app.INT_TEMP.Value = Group.green.p.temp_int;
                elseif(strcmp(selected_user,'Care-giver'))
                    app.HeartRatepeakprominenceEditField.Value = Group.green.c.hr;
                    app.ElectrodermalActchangeEditField.Value = Group.green.c.eda;
                    app.TemperaturechangeEditField.Value = Group.green.c.temp;
                    app.SF_HR.Value = Group.green.c.hr_sf;
                    app.SF_EDA.Value = Group.green.c.eda_sf;
                    app.SF_TEMP.Value = Group.green.c.temp_sf;   
                    app.INT_EDA.Value = Group.green.c.eda_int;
                    app.INT_TEMP.Value = Group.green.c.temp_int;
                end            
            elseif(strcmp(selected_group,'Orange'))
                if(strcmp(selected_user,'Participant'))
                    app.HeartRatepeakprominenceEditField.Value = Group.orange.p.hr;
                    app.ElectrodermalActchangeEditField.Value = Group.orange.p.eda;
                    app.TemperaturechangeEditField.Value = Group.orange.p.temp;
                    app.SF_HR.Value = Group.orange.p.hr_sf;
                    app.SF_EDA.Value = Group.orange.p.eda_sf;
                    app.SF_TEMP.Value = Group.orange.p.temp_sf; 
                    app.INT_EDA.Value = Group.orange.p.eda_int;
                    app.INT_TEMP.Value = Group.orange.p.temp_int;
                elseif(strcmp(selected_user,'Care-giver'))
                    app.HeartRatepeakprominenceEditField.Value = Group.orange.c.hr;
                    app.ElectrodermalActchangeEditField.Value = Group.orange.c.eda;
                    app.TemperaturechangeEditField.Value = Group.orange.c.temp;
                    app.SF_HR.Value = Group.orange.c.hr_sf;
                    app.SF_EDA.Value = Group.orange.c.eda_sf;
                    app.SF_TEMP.Value = Group.orange.c.temp_sf; 
                    app.INT_EDA.Value = Group.orange.c.eda_int;
                    app.INT_TEMP.Value = Group.orange.c.temp_int;
                end                
            elseif(strcmp(selected_group,'Red'))
                if(strcmp(selected_user,'Participant'))
                    app.HeartRatepeakprominenceEditField.Value = Group.red.p.hr;
                    app.ElectrodermalActchangeEditField.Value = Group.red.p.eda;
                    app.TemperaturechangeEditField.Value = Group.red.p.temp;
                    app.SF_HR.Value = Group.red.p.hr_sf;
                    app.SF_EDA.Value = Group.red.p.eda_sf;
                    app.SF_TEMP.Value = Group.red.p.temp_sf;  
                    app.INT_EDA.Value = Group.red.p.eda_int;
                    app.INT_TEMP.Value = Group.red.p.temp_int;
                elseif(strcmp(selected_user,'Care-giver'))
                    app.HeartRatepeakprominenceEditField.Value = Group.red.c.hr;
                    app.ElectrodermalActchangeEditField.Value = Group.red.c.eda;
                    app.TemperaturechangeEditField.Value = Group.red.c.temp;
                    app.SF_HR.Value = Group.red.c.hr_sf;
                    app.SF_EDA.Value = Group.red.c.eda_sf;
                    app.SF_TEMP.Value = Group.red.c.temp_sf;  
                    app.INT_EDA.Value = Group.red.c.eda_int;
                    app.INT_TEMP.Value = Group.red.c.temp_int;
                end             
            elseif(strcmp(selected_group,'Yellow'))
                if(strcmp(selected_user,'Participant'))
                    app.HeartRatepeakprominenceEditField.Value = Group.yellow.p.hr;
                    app.ElectrodermalActchangeEditField.Value = Group.yellow.p.eda;
                    app.TemperaturechangeEditField.Value = Group.yellow.p.temp;
                    app.SF_HR.Value = Group.yellow.p.hr_sf;
                    app.SF_EDA.Value = Group.yellow.p.eda_sf;
                    app.SF_TEMP.Value = Group.yellow.p.temp_sf; 
                    app.INT_EDA.Value = Group.yellow.p.eda_int;
                    app.INT_TEMP.Value = Group.yellow.p.temp_int;
                elseif(strcmp(selected_user,'Care-giver'))
                    app.HeartRatepeakprominenceEditField.Value = Group.yellow.c.hr;
                    app.ElectrodermalActchangeEditField.Value = Group.yellow.c.eda;
                    app.TemperaturechangeEditField.Value = Group.yellow.c.temp;
                    app.SF_HR.Value = Group.yellow.c.hr_sf;
                    app.SF_EDA.Value = Group.yellow.c.eda_sf;
                    app.SF_TEMP.Value = Group.yellow.c.temp_sf;  
                    app.INT_EDA.Value = Group.yellow.c.eda_int;
                    app.INT_TEMP.Value = Group.yellow.c.temp_int;
                end              
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Group Threshold default values = Blue,Participant
            populate_group_threshold(app,'Blue','Participant');
            
            % Initialize the Data struct
            init_data_struct(app);
            
        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            % When the select files button is pressed we choose a
            % folder and we extract the data from the .CSV data files to
            % put them into the variables
            
            path = uigetdir;
            if isequal(path,0)
                disp('User selected Cancel');
                return;
            else
                disp(['User selected folder: ', path]);
                listing = dir(path);
                row_offset = 1;
                col_offset = 0;
                for i = 1:size(listing)
                    % We go through each files and we read the CSV into
                    % a table that we save in the appropriate file.
                    file_name = listing(i).name;
                    full_path = strcat(path,'\',file_name);
                    if(contains(file_name, 'p_bvp'))
                        disp(['File : ',file_name,' is bvp p']);
                        app.BVP_p =readtable(full_path);
                    elseif(contains(file_name, 'p_hr'))
                        disp(['File : ',file_name,' is HR p']);
                        app.HR_p =readtable(full_path);
                    elseif(contains(file_name, 'p_temp'))
                        disp(['File : ',file_name,' is TEMP p']);
                        app.TEMP_p =readtable(full_path);
                    elseif(contains(file_name, 'p_eda'))
                        disp(['File : ',file_name,' is EDA p']);
                        app.EDA_p =readtable(full_path);
                    elseif(contains(file_name, 'c_bvp'))
                        disp(['File : ',file_name,' is bvp c']);
                        app.BVP_c =readtable(full_path);
                    elseif(contains(file_name, 'c_hr'))
                        disp(['File : ',file_name,' is HR c']);
                        app.HR_c =readtable(full_path);
                    elseif(contains(file_name, 'c_temp'))
                        disp(['File : ',file_name,' is TEMP c']);
                        app.TEMP_c =readtable(full_path);
                    elseif(contains(file_name, 'c_eda'))
                        disp(['File : ',file_name,' is EDA c']);
                        app.EDA_c =readtable(full_path);
                    end
                    
                end
                app.readyLamp.Color = [0,1,0];
                app.is_ready = 1;
                
                % Load into Data structure
                load_data_struct(app);
            end
        end

        % Button pushed function: FindtimepointsButton
        function FindtimepointsButtonPushed(app, event)
            disp(app.saving_directory)
            
            % Get the time_stamp for saving purposes
            app.time_stamp = floor(now*100000);
            
            % When the find time points button is pushed we load 
            % all the necessary variables before calling the find events function
            app.start_time = app.SystemStartTimeEditField.Value;
            
            if(app.is_ready && app.start_time > 0)
                clc;
                disp('Program Launched');
                disp('Synchronizing physiological time with start time...');
                update_data_struct(app,'corr_time');
                disp('Loading variables...');
                app.number_markers = app.NumberofMarkersEditField.Value;
                app.group = app.SelectGroupDropDown.Value;
                temp_data = load('Group.mat');
                
                % Here we load the thresholds for the group we are trying to do
                if(strcmp(app.group,'Blue'))
                    app.thresholds = temp_data.Group.blue;
                elseif(strcmp(app.group,'Green'))
                    app.thresholds = temp_data.Group.green;
                elseif(strcmp(app.group,'Orange'))
                    app.thresholds = temp_data.Group.orange;
                elseif(strcmp(app.group,'Red'))
                    app.thresholds = temp_data.Group.red;
                elseif(strcmp(app.group,'Yellow'))
                    app.thresholds = temp_data.Group.yellow;
                end
                
                disp('Calculating signal quality for participants...')
                [bvp_struct.p,sc_struct.p,temp_struct.p] = get_sqi(app,"p");                
                disp('Calculating signal quality for care-giver...')
                [bvp_struct.c,sc_struct.c,temp_struct.c] = get_sqi(app,"c");
                
                if(app.is_debug == 0)
                    disp('Saving signal quality for care-giver...');
                    save_sqi(app,"c",app.Data.c.bvp.score,app.Data.c.sc.score,app.Data.c.temp.score);
                    disp('Saving signal quality for participants...');
                    save_sqi(app,"p",app.Data.p.bvp.score,app.Data.p.sc.score,app.Data.p.temp.score);
                end
                disp('Overwrite raw data with filtered signal for EDA and TEMP...')
                
                disp('Finding events for participants...');
                [event_struct.p,hr_struct.p,temp_struct.p,sc_struct.p] = find_events(app,"p");
                disp('Finding events for care-giver...');
                [event_struct.c,hr_struct.c,temp_struct.c,sc_struct.c] = find_events(app,"c");
                
                if(app.is_debug == 0)
                    disp('Save events for participants...');
                    save_events(app,event_struct.p,"p");
                    disp('Save events for care-giver...');
                    save_events(app,event_struct.c,"c");
                end
                
                if(app.ParticipantCheckBox.Value == 1)
                    disp('Calculating Participant HRV features...');
                    [hrv_features_struct.p]=calc_hrv_features(app,'p',20);
                end
                
                if(app.CaregiverCheckBox.Value == 1)
                    disp('Calculating Caregiver HRV features...');
                    [hrv_features_struct.c]=calc_hrv_features(app,'c',20);
                end
                
                assignin('base','hrv_features_struct',hrv_features_struct);
                
                if(app.ParticipantCheckBox.Value == 1)
                    disp('Plotting Participant...');
                    plot_data_markers('Participant',hrv_features_struct.p,sc_struct.p,hr_struct.p,temp_struct.p,event_struct.p,'r');
                end
                
                if(app.CaregiverCheckBox.Value == 1)
                    disp('Plotting Care-giver...');
                    plot_data_markers('Caregiver',hrv_features_struct.c,sc_struct.c,hr_struct.c,temp_struct.c,event_struct.c,'b');
                end
                disp('Program Terminated!');
                
                % Master structure with all the Data
                assignin('base','Data',app.Data);
            else
                disp('Select a folder first and make sure start time > 0');
            end
            
        end

        % Button pushed function: UpdateThresholdButton
        function UpdateThresholdButtonPushed(app, event)
            % Collecting the threshold from the input fields
            HR_bpm = app.HeartRatepeakprominenceEditField.Value;
            EDA_change = app.ElectrodermalActchangeEditField.Value;
            TEMP_change = app.TemperaturechangeEditField.Value;
            HR_SF = app.SF_HR.Value;
            EDA_SF = app.SF_EDA.Value;
            TEMP_SF = app.SF_TEMP.Value;
            EDA_INT = app.INT_EDA.Value;
            TEMP_INT = app.INT_TEMP.Value;
            selected_group = app.SelectGroupDropDownThreshold.Value;
            selected_user = app.SelectUserDropDown.Value;
            
            % Loading the Group structure
            Group = load('Group.mat');
            Group = Group.Group;
            
            % Changing the right field in the Group structure
            if(strcmp(selected_group,'Blue'))
                if(strcmp(selected_user,'Participant'))
                    Group.blue.p.eda = EDA_change;
                    Group.blue.p.hr = HR_bpm;
                    Group.blue.p.temp = TEMP_change;
                    Group.blue.p.hr_sf = HR_SF;
                    Group.blue.p.eda_sf = EDA_SF;
                    Group.blue.p.temp_sf = TEMP_SF;
                    Group.blue.p.eda_int = EDA_INT;
                    Group.blue.p.temp_int = TEMP_INT;
                elseif(strcmp(selected_user,'Care-giver'))
                    Group.blue.c.eda = EDA_change;
                    Group.blue.c.hr = HR_bpm;
                    Group.blue.c.temp = TEMP_change; 
                    Group.blue.c.hr_sf = HR_SF;
                    Group.blue.c.eda_sf = EDA_SF;
                    Group.blue.c.temp_sf = TEMP_SF;  
                    Group.blue.c.eda_int = EDA_INT;
                    Group.blue.c.temp_int = TEMP_INT;
                end
            elseif(strcmp(selected_group,'Green'))
                if(strcmp(selected_user,'Participant'))
                    Group.green.p.eda = EDA_change;
                    Group.green.p.hr = HR_bpm;
                    Group.green.p.temp = TEMP_change;
                    Group.green.p.hr_sf = HR_SF;
                    Group.green.p.eda_sf = EDA_SF;
                    Group.green.p.temp_sf = TEMP_SF;  
                    Group.green.p.eda_int = EDA_INT;
                    Group.green.p.temp_int = TEMP_INT;
                elseif(strcmp(selected_user,'Care-giver'))
                    Group.green.c.eda = EDA_change;
                    Group.green.c.hr = HR_bpm;
                    Group.green.c.temp = TEMP_change;
                    Group.green.c.hr_sf = HR_SF;
                    Group.green.c.eda_sf = EDA_SF;
                    Group.green.c.temp_sf = TEMP_SF;  
                    Group.green.c.eda_int = EDA_INT;
                    Group.green.c.temp_int = TEMP_INT;
                end            
            elseif(strcmp(selected_group,'Orange'))
                if(strcmp(selected_user,'Participant'))
                    Group.orange.p.eda = EDA_change;
                    Group.orange.p.hr = HR_bpm;
                    Group.orange.p.temp = TEMP_change;
                    Group.orange.p.hr_sf = HR_SF;
                    Group.orange.p.eda_sf = EDA_SF;
                    Group.orange.p.temp_sf = TEMP_SF;   
                    Group.orange.p.eda_int = EDA_INT;
                    Group.orange.p.temp_int = TEMP_INT;
                elseif(strcmp(selected_user,'Care-giver'))
                    Group.orange.c.eda = EDA_change;
                    Group.orange.c.hr = HR_bpm;
                    Group.orange.c.temp = TEMP_change; 
                    Group.orange.c.hr_sf = HR_SF;
                    Group.orange.c.eda_sf = EDA_SF;
                    Group.orange.c.temp_sf = TEMP_SF;  
                    Group.orange.c.eda_int = EDA_INT;
                    Group.orange.c.temp_int = TEMP_INT;
                end                
            elseif(strcmp(selected_group,'Red'))
                if(strcmp(selected_user,'Participant'))
                    Group.red.p.eda = EDA_change;
                    Group.red.p.hr = HR_bpm;
                    Group.red.p.temp = TEMP_change;
                    Group.red.p.hr_sf = HR_SF;
                    Group.red.p.eda_sf = EDA_SF;
                    Group.red.p.temp_sf = TEMP_SF; 
                    Group.red.p.eda_int = EDA_INT;
                    Group.red.p.temp_int = TEMP_INT;
                elseif(strcmp(selected_user,'Care-giver'))
                    Group.red.c.eda = EDA_change;
                    Group.red.c.hr = HR_bpm;
                    Group.red.c.temp = TEMP_change;
                    Group.red.c.hr_sf = HR_SF;
                    Group.red.c.eda_sf = EDA_SF;
                    Group.red.c.temp_sf = TEMP_SF;  
                    Group.red.c.eda_int = EDA_INT;
                    Group.red.c.temp_int = TEMP_INT;
                end             
            elseif(strcmp(selected_group,'Yellow'))
                if(strcmp(selected_user,'Participant'))
                    Group.yellow.p.eda = EDA_change;
                    Group.yellow.p.hr = HR_bpm;
                    Group.yellow.p.temp = TEMP_change;
                    Group.yellow.p.hr_sf = HR_SF;
                    Group.yellow.p.eda_sf = EDA_SF;
                    Group.yellow.p.temp_sf = TEMP_SF; 
                    Group.yellow.p.eda_int = EDA_INT;
                    Group.yellow.p.temp_int = TEMP_INT;
                elseif(strcmp(selected_user,'Care-giver'))
                    Group.yellow.c.eda = EDA_change;
                    Group.yellow.c.hr = HR_bpm;
                    Group.yellow.c.temp = TEMP_change;  
                    Group.yellow.c.hr_sf = HR_SF;
                    Group.yellow.c.eda_sf = EDA_SF;
                    Group.yellow.c.temp_sf = TEMP_SF;   
                    Group.yellow.c.eda_int = EDA_INT;
                    Group.yellow.c.temp_int = TEMP_INT;
                end              
            end
            
            % Saving the Group data
            filename = strcat(app.original_directory,'/Group.mat');
            save(filename,'Group');
            disp('Updated the Group threshold!');
        end

        % Value changed function: SelectUserDropDown
        function SelectUserDropDownValueChanged(app, event)
            sel_user = app.SelectUserDropDown.Value;
            sel_group = app.SelectGroupDropDownThreshold.Value;
            populate_group_threshold(app,sel_group,sel_user);
        end

        % Value changed function: SelectGroupDropDownThreshold
        function SelectGroupDropDownThresholdValueChanged(app, event)
            sel_user = app.SelectUserDropDown.Value;
            sel_group = app.SelectGroupDropDownThreshold.Value;
            populate_group_threshold(app,sel_group,sel_user);
        end

        % Button pushed function: SelectDirectoryButton
        function SelectDirectoryButtonPushed(app, event)
            path = uigetdir;
            if(path ~= 0)
               app.saving_directory = path;
               disp(['updated saving directory to : ',path]);
            end
        end

        % Value changed function: DebugModeSwitch
        function DebugModeSwitchValueChanged(app, event)
            value = app.DebugModeSwitch.Value;
            if(strcmp(value,'On'))
                app.readyLamp.Color = [0,0,1];
                app.readyLampLabel.Text = 'debug';
                app.is_debug = 1;
            else
                app.is_debug = 0;
                app.is_ready = 0;
                app.readyLamp.Color = [1,0,0];
                app.readyLampLabel.Text = 'ready';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EventFinderUIFigure = uifigure;
            app.EventFinderUIFigure.Name = 'Event Finder';
            app.FileMenu = uimenu(app.EventFinderUIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            app.TabGroup = uitabgroup(app.EventFinderUIFigure);
            app.EventFinderTab = uitab(app.TabGroup);
            app.EventFinderTab.Title = 'Event Finder';
            app.SelectFilesButton = uibutton(app.EventFinderTab, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.FindtimepointsButton = uibutton(app.EventFinderTab, 'push');
            app.FindtimepointsButton.ButtonPushedFcn = createCallbackFcn(app, @FindtimepointsButtonPushed, true);
            app.FindtimepointsButton.Text = 'Find time points';
            app.readyLampLabel = uilabel(app.EventFinderTab);
            app.readyLampLabel.Text = 'ready';
            app.readyLamp = uilamp(app.EventFinderTab);
            app.MarkerFileNameEditFieldLabel = uilabel(app.EventFinderTab);
            app.MarkerFileNameEditFieldLabel.Text = 'Marker File Name';
            app.MarkerFileNameEditField = uieditfield(app.EventFinderTab, 'text');
            app.SystemStartTimeEditFieldLabel = uilabel(app.EventFinderTab);
            app.SystemStartTimeEditFieldLabel.Text = 'System Start Time';
            app.SystemStartTimeEditField = uieditfield(app.EventFinderTab, 'numeric');
            app.SystemStartTimeEditField.Limits = [0 Inf];
            app.NumberofMarkersEditFieldLabel = uilabel(app.EventFinderTab);
            app.NumberofMarkersEditFieldLabel.Text = 'Number of Markers';
            app.NumberofMarkersEditField = uieditfield(app.EventFinderTab, 'numeric');
            app.NumberofMarkersEditField.Limits = [1 Inf];
            app.NumberofMarkersEditField.Value = 1;
            app.SelectGroupDropDownLabel = uilabel(app.EventFinderTab);
            app.SelectGroupDropDownLabel.Text = 'Select Group';
            app.SelectGroupDropDown = uidropdown(app.EventFinderTab);
            app.SelectGroupDropDown.Items = {'Blue', 'Green', 'Orange', 'Red', 'Yellow'};
            app.SelectGroupDropDown.Value = 'Blue';
            app.ParticipantCheckBox = uicheckbox(app.EventFinderTab);
            app.ParticipantCheckBox.Text = 'Participant';
            app.ParticipantCheckBox.Value = true;
            app.CaregiverCheckBox = uicheckbox(app.EventFinderTab);
            app.CaregiverCheckBox.Text = 'Caregiver';
            app.CaregiverCheckBox.Value = true;
            app.PlotGraphforLabel = uilabel(app.EventFinderTab);
            app.PlotGraphforLabel.Text = 'Plot Graph for';
            app.ShowMarkersforLabel = uilabel(app.EventFinderTab);
            app.ShowMarkersforLabel.Text = 'Show Markers for';
            app.EDACheckBox = uicheckbox(app.EventFinderTab);
            app.EDACheckBox.Text = 'EDA';
            app.EDACheckBox.Value = true;
            app.HRCheckBox = uicheckbox(app.EventFinderTab);
            app.HRCheckBox.Text = 'HR';
            app.HRCheckBox.Value = true;
            app.TempCheckBox = uicheckbox(app.EventFinderTab);
            app.TempCheckBox.Text = 'Temp';
            app.TempCheckBox.Value = true;
            app.LoadDataLabel = uilabel(app.EventFinderTab);
            app.LoadDataLabel.Text = 'Load Data';
            app.SelectDirectoryButton = uibutton(app.EventFinderTab, 'push');
            app.SelectDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButtonPushed, true);
            app.SelectDirectoryButton.Text = 'Select Directory';
            app.SavingDirectoryLabel = uilabel(app.EventFinderTab);
            app.SavingDirectoryLabel.Text = 'Saving Directory';
            app.DebugModeSwitchLabel = uilabel(app.EventFinderTab);
            app.DebugModeSwitchLabel.Text = 'Debug Mode';
            app.DebugModeSwitch = uiswitch(app.EventFinderTab, 'slider');
            app.DebugModeSwitch.ValueChangedFcn = createCallbackFcn(app, @DebugModeSwitchValueChanged, true);
            app.GroupThresholdTab = uitab(app.TabGroup);
            app.GroupThresholdTab.Title = 'Group Threshold';
            app.SelectGroupDropDown_2Label = uilabel(app.GroupThresholdTab);
            app.SelectGroupDropDown_2Label.Text = 'Select Group';
            app.SelectGroupDropDownThreshold = uidropdown(app.GroupThresholdTab);
            app.SelectGroupDropDownThreshold.Items = {'Blue', 'Green', 'Orange', 'Red', 'Yellow'};
            app.SelectGroupDropDownThreshold.ValueChangedFcn = createCallbackFcn(app, @SelectGroupDropDownThresholdValueChanged, true);
            app.SelectGroupDropDownThreshold.Value = 'Blue';
            app.UpdateThresholdButton = uibutton(app.GroupThresholdTab, 'push');
            app.UpdateThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateThresholdButtonPushed, true);
            app.UpdateThresholdButton.Text = 'Update Threshold';
            app.HeartRatepeakprominenceEditFieldLabel = uilabel(app.GroupThresholdTab);
            app.HeartRatepeakprominenceEditFieldLabel.Text = {'Heart Rate'; 'peak prominence'};
            app.HeartRatepeakprominenceEditField = uieditfield(app.GroupThresholdTab, 'numeric');
            app.HeartRatepeakprominenceEditField.Limits = [0 Inf];
            app.HeartRatepeakprominenceEditField.Value = 10;
            app.ElectrodermalActchangeEditFieldLabel = uilabel(app.GroupThresholdTab);
            app.ElectrodermalActchangeEditFieldLabel.Text = {'Electrodermal Act'; 'change'};
            app.ElectrodermalActchangeEditField = uieditfield(app.GroupThresholdTab, 'numeric');
            app.ElectrodermalActchangeEditField.Limits = [0 Inf];
            app.ElectrodermalActchangeEditField.Value = 0.1;
            app.TemperaturechangeEditFieldLabel = uilabel(app.GroupThresholdTab);
            app.TemperaturechangeEditFieldLabel.Text = {'Temperature'; 'change'};
            app.TemperaturechangeEditField = uieditfield(app.GroupThresholdTab, 'numeric');
            app.TemperaturechangeEditField.Limits = [0 Inf];
            app.TemperaturechangeEditField.Value = 0.1;
            app.SelectUserDropDownLabel = uilabel(app.GroupThresholdTab);
            app.SelectUserDropDownLabel.Text = 'Select User';
            app.SelectUserDropDown = uidropdown(app.GroupThresholdTab);
            app.SelectUserDropDown.Items = {'Participant', 'Care-giver'};
            app.SelectUserDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectUserDropDownValueChanged, true);
            app.SelectUserDropDown.Value = 'Participant';
            app.xEditFieldLabel = uilabel(app.GroupThresholdTab);
            app.xEditFieldLabel.Text = 'x';
            app.SF_HR = uieditfield(app.GroupThresholdTab, 'numeric');
            app.SF_HR.Limits = [0 Inf];
            app.xEditField_2Label = uilabel(app.GroupThresholdTab);
            app.xEditField_2Label.Text = 'x';
            app.SF_EDA = uieditfield(app.GroupThresholdTab, 'numeric');
            app.SF_EDA.Limits = [0 Inf];
            app.xEditField_3Label = uilabel(app.GroupThresholdTab);
            app.xEditField_3Label.Text = 'x';
            app.SF_TEMP = uieditfield(app.GroupThresholdTab, 'numeric');
            app.SF_TEMP.Limits = [0 Inf];
            app.ScalingFactorLabel = uilabel(app.GroupThresholdTab);
            app.ScalingFactorLabel.Text = 'Scaling Factor';
            app.usLabel = uilabel(app.GroupThresholdTab);
            app.usLabel.Text = {'us /'; ''};
            app.bpmLabel = uilabel(app.GroupThresholdTab);
            app.bpmLabel.Text = 'bpm';
            app.CLabel = uilabel(app.GroupThresholdTab);
            app.CLabel.Text = 'C /';
            app.INT_EDA = uieditfield(app.GroupThresholdTab, 'numeric');
            app.INT_TEMP = uieditfield(app.GroupThresholdTab, 'numeric');
            app.sLabel_2 = uilabel(app.GroupThresholdTab);
            app.sLabel_2.Text = 's';
            app.sLabel_1 = uilabel(app.GroupThresholdTab);
            app.sLabel_1.Text = 's';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CSVtoTimePts

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EventFinderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EventFinderUIFigure)
        end
    end
end